classdef appTuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierTuner  matlab.ui.Figure
        Gauge         matlab.ui.control.SemicircularGauge
        Switch        matlab.ui.control.Switch
        Nota          matlab.ui.control.Label
        Octava        matlab.ui.control.Label
        Frecuencia    matlab.ui.control.Label
        HzLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        start = false % start
    end

    methods (Access = private)
    
        function escuchar(app)
            notas = ["G#", "A", "A#", "B", "C", "C#", "D", "D#", "E", "F", "F#", "G"];
            nota_base = 440;
            num_nota = @(frecuencia, nota_base)((log2(frecuencia/nota_base)*12)+49);
            
            Fs = 192000;
            nBits = 24;
            nChan = 1;
            audio=audiorecorder(Fs, nBits, nChan);
            pause(0.5);
            while(app.start)
               	record(audio);
               	pause(.70);
               	stop(audio);
               	vectorAudio=getaudiodata(audio);
               	transformada=abs(fft(vectorAudio));
                %quitar las frecuencias debajo de 15 hz y el reflejo de la transformada que se encuentra en la segunda mitad
                transformada = [ones(2,1); transformada(3:end/4)];
               	l = length(transformada);
                frecuencias = linspace(0, Fs/4, l);
                %calculo del producto de espectro Armónico
                magnitud_fundamental = transformada;
                armonicos = 4;
                for i =2:armonicos
                    temp = [];
                    for n = 1:i:(length(transformada)-i-2)
                        temp = [temp; sum(transformada(n:(n+i-1),1))/i];
                    end
                    magnitud_fundamental = magnitud_fundamental.*([temp; ones(length(magnitud_fundamental)-length(temp),1)]);
                    
                end
                magnitud_fundamental = magnitud_fundamental.^(1/armonicos);
                magnitud_fundamental = [ones(5,1); magnitud_fundamental(6:end)];
                %tomo la nota fundamental para mostrarla
                [~, indice_fundamental] = max(magnitud_fundamental);
                frecuencia_fundamental = frecuencias(indice_fundamental);
                numero_nota = abs(num_nota(frecuencia_fundamental,nota_base));
                entero_nota =round(numero_nota);
                residuo_nota = numero_nota-entero_nota;
                temperado = residuo_nota*100;
                letra_nota = notas(mod(entero_nota,12)+1);
                octava = floor((entero_nota+8)/12);
                % implementacion en App
                app.Frecuencia.Text = num2str(frecuencia_fundamental,"%.2f");
                app.Octava.Text = int2str(octava);
                app.Nota.Text = letra_nota;
                app.Gauge.Value = temperado;
            end
                app.Frecuencia.Text = "0.00";
                app.Octava.Text = "";
                app.Nota.Text = "";
                app.Gauge.Value = 0;
        end
        
    end


    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %value = app.Switch.Value;
            if app.start == false
                app.start = true;
                escuchar(app);
            elseif app.start == true
                app.start = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierTuner = uifigure;
            app.FourierTuner.Name = 'UI Figure';
            app.Gauge = uigauge(app.FourierTuner, 'semicircular');
            app.Gauge.Limits = [-50 50];
            app.Switch = uiswitch(app.FourierTuner, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Nota = uilabel(app.FourierTuner);
            app.Nota.Text = '';
            app.Octava = uilabel(app.FourierTuner);
            app.Octava.Text = '';
            app.Frecuencia = uilabel(app.FourierTuner);
            app.Frecuencia.Text = '0.00';
            app.HzLabel = uilabel(app.FourierTuner);
            app.HzLabel.Text = 'Hz';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appTuner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierTuner)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierTuner)
        end
    end
end